classdef NBCC_GroundMotionSelectionAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        StopButton                    matlab.ui.control.StateButton
        PauseButton                   matlab.ui.control.StateButton
        BrowseButton                  matlab.ui.control.Button
        switchTarget                  matlab.ui.control.Switch
        EnableLabel                   matlab.ui.control.Label
        TargetSpectraPanel            matlab.ui.container.Panel
        targetWhat                    matlab.ui.container.ButtonGroup
        MeanCOVButton                 matlab.ui.control.RadioButton
        MeanButton                    matlab.ui.control.RadioButton
        meanTarget                    matlab.ui.container.ButtonGroup
        UHSButton                     matlab.ui.control.RadioButton
        CMSButton                     matlab.ui.control.RadioButton
        GeneralInputsPanel            matlab.ui.container.Panel
        SaT1_bldg                     matlab.ui.control.NumericEditField
        SaT1gEditFieldLabel           matlab.ui.control.Label
        T90pc                         matlab.ui.control.NumericEditField
        T90sEditFieldLabel            matlab.ui.control.Label
        T1_bldg                       matlab.ui.control.NumericEditField
        T1sEditFieldLabel             matlab.ui.control.Label
        Vs30ms                        matlab.ui.control.NumericEditField
        Vs30msEditFieldLabel          matlab.ui.control.Label
        Latitude                      matlab.ui.control.NumericEditField
        LatitudeEditField_2Label      matlab.ui.control.Label
        Longitude                     matlab.ui.control.NumericEditField
        LongitudeEditFieldLabel       matlab.ui.control.Label
        Location                      matlab.ui.control.EditField
        LocationEditFieldLabel        matlab.ui.control.Label
        FilteringCriteria             matlab.ui.container.Panel
        Vs30Label_2                   matlab.ui.control.Label
        distMin                       matlab.ui.control.NumericEditField
        Vs30Label                     matlab.ui.control.Label
        Vs30UpperLimit                matlab.ui.control.NumericEditField
        Vs30LowerLimit                matlab.ui.control.NumericEditField
        DeltaM                        matlab.ui.control.NumericEditField
        MEditFieldLabel               matlab.ui.control.Label
        InputFileName                 matlab.ui.control.EditField
        InputfileLabel                matlab.ui.control.Label
        DirName                       matlab.ui.control.EditField
        ResultDirNameLabel            matlab.ui.control.Label
        SelectedGM_sigma              matlab.ui.control.Image
        SelectedGM_median             matlab.ui.control.Image
        SelectedGM_Sa                 matlab.ui.control.Image
        SelectedRecordsTable          matlab.ui.control.Table
        MaxScalingFac                 matlab.ui.control.NumericEditField
        MaximumScalingEditFieldLabel  matlab.ui.control.Label
        SelectGroundMotionRecordsButton  matlab.ui.control.Button
        ScaleGM                       matlab.ui.control.CheckBox
        ReadTableButton               matlab.ui.control.Button
        UserGMInputTable              matlab.ui.control.Table
    end

    
    properties (Access = public)
        storeOldMaxSFVal % This stores scaling factor entered by the user, and repopulates if scaling option is clicked twice
    end
    
    methods (Access = public)
        function checkPauseStopStatus(app,flag)
            % This function can be called at any time to check that status of the pause and stop buttons.
            % If paused, it will wait until un-paused.
            % If stopped, it will throw an error to break execution. The error will not be thrown.
            % If flag input is included and =1, buttons will be reset to de-selected.
            % if flag input is included and =2, all buttons will be disabled (used during file writing and other processes)
            % if flag input is included and =3, all buttons will be enabled (used during file writing and other processes)
            if nargin > 1
                switch flag
                    case 1 % reset pause/stop button
                        app.PauseButton.Value = false;
                        PauseButtonValueChanged(app, [])
                        app.StopButton.Value = false;
                    case 2 % disable buttons
                        app.PauseButton.Enable = 'off';
                        app.StopButton.Enable = 'off';
                    case 3 % enable buttons
                        app.PauseButton.Enable = 'on';
                        app.StopButton.Enable = 'on';
                    otherwise
                        error('Unknown flag value.')
                end
                drawnow()
            else
                if app.StopButton.Value
                    checkPauseStopStatus(app,1) % reset pause/stop buttons
                    error('MATLAB:eyeTrackAPP:abort','User aborted execution.')
                end
                if app.PauseButton.Value
                    waitfor(app.PauseButton,'Value',false)
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,"center")
            app.UIFigure.Visible = 'on';            

        % This nice app needs other functions and hence matlab's working directory to be where they are saved, 
        % regardless of whether there is an existing instance of matlab (or what it's been doing)
            appPath = mfilename('fullpath'); 
            [filePath, ~, ~] = fileparts(appPath); cd(filePath); 
            
            grayImage = 255*0.94 * ones(2, 2, 3, 'uint8');
            app.SelectedGM_Sa.ImageSource = grayImage;
            app.SelectedGM_median.ImageSource = grayImage;
            app.SelectedGM_sigma.ImageSource = grayImage;

            % make all columns in the input table editable
            ncols = size(app.UserGMInputTable.ColumnName, 1);
            app.UserGMInputTable.ColumnEditable = true(1,ncols); 
            app.UserGMInputTable.ColumnSortable = false(1,ncols); 
        end

        % Button pushed function: ReadTableButton
        function ReadTableButtonPushed(app, event)
        
        % error handling if file does not exist
            if ~isfile(app.InputFileName.Value)
                message = ['File "', app.InputFileName.Value, '" not found.'];
                fig = uifigure; uialert(fig, message,'Invalid File'); 
                return; 
            end
    
        % read general input details from excel. Values are editable through the app after reading
            try
                locationName = readtable(app.InputFileName.Value, "Sheet","Inputs","Range", "C5:C5",'ReadVariableNames', false);
                app.Location.Value = string(locationName.Var1);
                generalData  = readtable(app.InputFileName.Value, "Sheet","Inputs","Range", "C6:C11",'ReadVariableNames', false);
            catch
                fig = uifigure; message = ['Sheet "Inputs" may not exist in "', app.InputFileName.Value, '".'];
                uialert(fig, message, 'Warning', 'Icon', 'warning'); 
                return;
            end

            app.Longitude.Value = generalData.Var1(1, 1); app.Latitude.Value =  generalData.Var1(2, 1);
            app.Vs30ms.Value =    generalData.Var1(3, 1); app.T1_bldg.Value =   generalData.Var1(4, 1);
            app.T90pc.Value =     generalData.Var1(5, 1); app.SaT1_bldg.Value = generalData.Var1(6, 1);

            opts = detectImportOptions(app.InputFileName.Value, "Sheet","Inputs","DataRange", "J4:J9");
            opts = setvartype(opts, 'Var1', 'string'); % read everything as string
            filterData = readtable(app.InputFileName.Value, opts);

            % replace non-entries with -99; note that 0 is an accepted value
            for i = [1:4, 6]; if isnan(str2double(filterData.Var1(i))); filterData.Var1(i) = -99; end; end

            app.DeltaM.Value =         str2double(filterData.Var1(1));
            app.distMin.Value =        str2double(filterData.Var1(2));
            app.Vs30LowerLimit.Value = str2double(filterData.Var1(3));
            app.Vs30UpperLimit.Value = str2double(filterData.Var1(4));
            app.MaxScalingFac.Value =  str2double(filterData.Var1(6));

            if strcmpi(filterData.Var1(5), 'Yes'); app.ScaleGM.Value = 1; else; app.ScaleGM.Value = 0; end

            t = readtable(app.InputFileName.Value, "Sheet","Inputs","Range", "B17:L20");

            app.UserGMInputTable.Data = t;
            
            % if user did not enter a new new, assign a name
            if isempty(strtrim(app.DirName.Value))
                if app.ScaleGM.Value == 0; scalingStr = 'none'; else; scalingStr = num2str(app.MaxScalingFac.Value); end
                default_name = sprintf('%iGM_%s_%s_SF%s_%s', sum(t.Var2, 'omitnan'), strrep(num2str(app.T1_bldg.Value), '.', 'p'), ...
                                    t.Var10{1,1}, scalingStr, app.Location.Value(1:3));
                
                if app.UHSButton.Value == 1;  default_name = [default_name, '_UHS']; end
                if app.MeanButton.Value == 1; default_name = [default_name, '_meanOnly']; end
                
                app.DirName.Value = default_name;
            end 

            % replace white spaces in the name, if any, with underscore
            if contains(app.DirName.Value, ' ')
                app.DirName.Value = strrep(app.DirName.Value, ' ', '_'); 
            end
            app.DirName.FontColor = [0 0 1]; % regardless of user-entry, change the font-color to blue
        end

        % Button pushed function: SelectGroundMotionRecordsButton
        function SelectGroundMotionRecordsButtonPushed(app, event)
            T = app.UserGMInputTable.Data;
            
            if isempty(T)
                message = ['Input table not imported.', newline, '1. Click on Browse, select the input .xls/xlsx file.', newline, '2. Click on "Read Table"', newline, '3. Click again on "Select Ground Motion Records"'];
                fig = uifigure; uialert(fig, message,'Error in Input variables.'); 
                return; 
            end

        % update the directory name if user changed any information after loading (number of GMs, etc.)
            oldDirName = app.DirName.Value; 
            patOld = digitsPattern + "GM"; 
            if contains(oldDirName, patOld)
                if app.ScaleGM.Value == 0; scalingStr = 'none'; else; scalingStr = num2str(app.MaxScalingFac.Value); end
                new_name = sprintf('%iGM_%s_%s_SF%s_%s', sum(T.Var2, 'omitnan'), strrep(num2str(app.T1_bldg.Value), '.', 'p'), ...
                                    T.Var10{1,1}, scalingStr, app.Location.Value(1:3));
                if app.UHSButton.Value == 1;  new_name = [new_name, '_UHS']; end
                if app.MeanButton.Value == 1; new_name = [new_name, '_meanOnly']; end
                
                app.DirName.Value = new_name;
            end

            tectonic = T.Var1; nGM = T.Var2; nGM(isnan(nGM)) = 0;
            DBsource = T.Var3; 
            T1 = T.Var4; SaT1 = T.Var5; 
            Tmin = T.Var6; Tmax = T.Var7; 
            Mag = T.Var8; Dist = T.Var9; 
            GMM = T.Var10; mech = T.Var11; 
                
            % assign all userInputs to a single variable
            userInputs.tectonic = tectonic(nGM ~= 0);
            userInputs.DB_LIST = DBsource(nGM ~= 0);
            userInputs.T1 = T1(nGM ~= 0); userInputs.SaT1 = SaT1(nGM ~= 0); 
            userInputs.Tmin = Tmin(nGM ~= 0); userInputs.Tmax = Tmax(nGM ~= 0); 
            userInputs.magLIST =  Mag(nGM ~= 0);  userInputs.distLIST = Dist(nGM ~= 0); 
            userInputs.GMM_LIST =  GMM(nGM ~= 0);  userInputs.mech = mech(nGM ~= 0); 
            userInputs.nGM_LIST = nGM(nGM ~= 0);
            userInputs.Tmin = Tmin(nGM ~= 0); userInputs.Tmax = Tmax(nGM ~= 0);

            % directory name to save detailed records
            userInputs.dirName = app.DirName.Value;

            userInputs.Vs30site = app.Vs30ms.Value; 
            userInputs.allowScaling = app.ScaleGM.Value; userInputs.maxSF = app.MaxScalingFac.Value;
            userInputs.magLimit = app.DeltaM.Value; userInputs.R_min = app.distMin.Value; 
            userInputs.Vs30Min = app.Vs30LowerLimit.Value; userInputs.Vs30Max = app.Vs30UpperLimit.Value; 

%             app.meanTarget
            userInputs.meanTargetCMS = app.CMSButton.Value; 

%             app.targetWhat
            userInputs.useVar = app.MeanCOVButton.Value; % set default to 1, change if MeanButton is selected 
            
            % Check if process is paused
            if ~isempty(app);  checkPauseStopStatus(app); end

            % call the master app function
            selectionResult = masterFunctionForApp_v3(userInputs);
%             selectionResult = app.UserGMInputTable.Data; % dummy assignment for design 
            
            app.SelectedRecordsTable.Data = selectionResult;
            app.SelectedRecordsTable.ColumnName = selectionResult.Properties.VariableNames ;
            
            % Create SelectedGMSpectra
            app.SelectedGM_Sa.ImageSource = sprintf('Summary_%s%s1_AllRecordsSa.png', userInputs.dirName, filesep);
            app.SelectedGM_median.ImageSource = sprintf('Summary_%s%s2_recordsMedian.png', userInputs.dirName, filesep);
            app.SelectedGM_sigma.ImageSource = sprintf('Summary_%s%s3_recordsSigma.png', userInputs.dirName, filesep);
        end

        % Value changed function: ScaleGM
        function ScaleGMValueChanged(app, event)
            value = app.ScaleGM.Value;
            if value == 0
                app.storeOldMaxSFVal = app.MaxScalingFac.Value; % store the existing value
                app.MaxScalingFac.Value = 0;
                app.MaxScalingFac.Enable = 'Off';
                app.MaximumScalingEditFieldLabel.Enable = 'Off';
            else
                app.MaxScalingFac.Value = app.storeOldMaxSFVal; % repopulate with the old value
                app.MaxScalingFac.Enable = 'On';
                app.MaximumScalingEditFieldLabel.Enable = 'On';
            end            
        end

        % Value changed function: switchTarget
        function switchTargetValueChanged(app, event)
            value = app.switchTarget.Value;
            switch value
                case '1'
                    app.TargetSpectraPanel.Enable = 'on';
                    app.EnableLabel.Text = '⇐ Disable';
                case '0'
                    app.TargetSpectraPanel.Enable = 'off';
                    app.EnableLabel.Text = '⇐ Enable';
            end
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); % create a dummy figure so that uigetfile doesn't minimize our GUI
            [file,~] = uigetfile({'*.xlsx;*.xls','Spreadsheet';'*.csv','Comma-separated value'}, 'Upload spreadsheet file for reading GM selection inputs');
            app.InputFileName.Value = file;
%             drawnow; figure(app.UIFigure); % focus back on the app. This works but sends everything else to background
            delete(f); % delete the dummy figure
        end

        % Value changed function: PauseButton
        function PauseButtonValueChanged(app, event)
            % When paused, the stop button should be disabled.
            if app.PauseButton.Value
                app.StopButton.Enable = 'off';
                app.PauseButton.Text = 'Resume';
            else
                app.StopButton.Enable = 'on';
                app.PauseButton.Text = 'Pause';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UserGMInputTable = uitable(app.UIFigure);
            app.UserGMInputTable.ColumnName = {'Tectonics'; '#GM'; 'Database'; 'T1(s)'; 'Sa(T1) (g)'; 'Tmin'; 'Tmax'; 'Magnitude'; 'Distance'; 'GMM'; 'Mechanism'};
            app.UserGMInputTable.RowName = {};
            app.ReadTableButton = uibutton(app.UIFigure, 'push');
            app.ReadTableButton.ButtonPushedFcn = createCallbackFcn(app, @ReadTableButtonPushed, true);
            app.ReadTableButton.Text = 'Read Table';
            app.ScaleGM = uicheckbox(app.UIFigure);
            app.ScaleGM.ValueChangedFcn = createCallbackFcn(app, @ScaleGMValueChanged, true);
            app.ScaleGM.Text = 'Scaling GM';
            app.ScaleGM.Value = true;
            app.SelectGroundMotionRecordsButton = uibutton(app.UIFigure, 'push');
            app.SelectGroundMotionRecordsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectGroundMotionRecordsButtonPushed, true);
            app.SelectGroundMotionRecordsButton.Text = 'Select Ground Motion Records';
            app.MaximumScalingEditFieldLabel = uilabel(app.UIFigure);
            app.MaximumScalingEditFieldLabel.Text = 'Maximum Scaling';
            app.MaxScalingFac = uieditfield(app.UIFigure, 'numeric');
            app.MaxScalingFac.Value = 5;
            app.SelectedRecordsTable = uitable(app.UIFigure);
            app.SelectedRecordsTable.ColumnName = '';
            app.SelectedRecordsTable.RowName = {};
            app.SelectedGM_Sa = uiimage(app.UIFigure);
            app.SelectedGM_median = uiimage(app.UIFigure);
            app.SelectedGM_sigma = uiimage(app.UIFigure);
            app.ResultDirNameLabel = uilabel(app.UIFigure);
            app.ResultDirNameLabel.Text = 'Results Directory';
            app.DirName = uieditfield(app.UIFigure, 'text');
            app.DirName.Placeholder = 'Enter the result directory name.';
            app.InputfileLabel = uilabel(app.UIFigure);
            app.InputfileLabel.Text = 'Input File';
            app.InputFileName = uieditfield(app.UIFigure, 'text');
            app.InputFileName.Value = 'Inputs_NBCC2020.xlsx';
            app.FilteringCriteria = uipanel(app.UIFigure);
            app.FilteringCriteria.Title = 'Filtering Criteria (-99, if not required)';
            app.MEditFieldLabel = uilabel(app.FilteringCriteria);
            app.MEditFieldLabel.Text = 'ΔM (±)';
            app.DeltaM = uieditfield(app.FilteringCriteria, 'numeric');
            app.DeltaM.Value = -99;
            app.Vs30LowerLimit = uieditfield(app.FilteringCriteria, 'numeric');
            app.Vs30LowerLimit.Value = -99;
            app.Vs30UpperLimit = uieditfield(app.FilteringCriteria, 'numeric');
            app.Vs30UpperLimit.Value = -99;
            app.Vs30Label = uilabel(app.FilteringCriteria);
            app.Vs30Label.Text = '≤ Vs30 (m/s) ≤';
            app.distMin = uieditfield(app.FilteringCriteria, 'numeric');
            app.distMin.Value = 20;
            app.Vs30Label_2 = uilabel(app.FilteringCriteria);
            app.Vs30Label_2.Text = 'R_min (km) ≥';
            app.GeneralInputsPanel = uipanel(app.UIFigure);
            app.GeneralInputsPanel.Title = 'General Inputs';
            app.LocationEditFieldLabel = uilabel(app.GeneralInputsPanel);
            app.LocationEditFieldLabel.Text = 'Location';
            app.Location = uieditfield(app.GeneralInputsPanel, 'text');
            app.Location.Value = 'Vancouver City Hall';
            app.LongitudeEditFieldLabel = uilabel(app.GeneralInputsPanel);
            app.LongitudeEditFieldLabel.Text = 'Longitude';
            app.Longitude = uieditfield(app.GeneralInputsPanel, 'numeric');
            app.Longitude.ValueDisplayFormat = '%.2f';
            app.Longitude.Value = -123.12;
            app.LatitudeEditField_2Label = uilabel(app.GeneralInputsPanel);
            app.LatitudeEditField_2Label.Text = 'Latitude';
            app.Latitude = uieditfield(app.GeneralInputsPanel, 'numeric');
            app.Latitude.Value = 49.25;
            app.Vs30msEditFieldLabel = uilabel(app.GeneralInputsPanel);
            app.Vs30msEditFieldLabel.Text = 'Vs30 (m/s)';
            app.Vs30ms = uieditfield(app.GeneralInputsPanel, 'numeric');
            app.Vs30ms.Value = 450;
            app.T1sEditFieldLabel = uilabel(app.GeneralInputsPanel);
            app.T1sEditFieldLabel.Text = 'T1 (s)';
            app.T1_bldg = uieditfield(app.GeneralInputsPanel, 'numeric');
            app.T1_bldg.Value = 1.17;
            app.T90sEditFieldLabel = uilabel(app.GeneralInputsPanel);
            app.T90sEditFieldLabel.Text = 'T90% (s)';
            app.T90pc = uieditfield(app.GeneralInputsPanel, 'numeric');
            app.T90pc.Value = 0.25;
            app.SaT1gEditFieldLabel = uilabel(app.GeneralInputsPanel);
            app.SaT1gEditFieldLabel.Text = 'Sa(T1) (g)';
            app.SaT1_bldg = uieditfield(app.GeneralInputsPanel, 'numeric');
            app.SaT1_bldg.Value = 0.3945;
            app.TargetSpectraPanel = uipanel(app.UIFigure);
            app.TargetSpectraPanel.Enable = 'off';
            app.TargetSpectraPanel.Title = 'Target Spectra';
            app.meanTarget = uibuttongroup(app.TargetSpectraPanel);
            app.CMSButton = uiradiobutton(app.meanTarget);
            app.CMSButton.Text = 'CMS';
            app.CMSButton.Value = true;
            app.UHSButton = uiradiobutton(app.meanTarget);
            app.UHSButton.Text = 'UHS';
            app.targetWhat = uibuttongroup(app.TargetSpectraPanel);
            app.MeanButton = uiradiobutton(app.targetWhat);
            app.MeanButton.Text = 'Mean';
            app.MeanCOVButton = uiradiobutton(app.targetWhat);
            app.MeanCOVButton.Text = 'Mean + COV';
            app.MeanCOVButton.Value = true;
            app.EnableLabel = uilabel(app.UIFigure);
            app.EnableLabel.Text = '⇐ Enable';
            app.switchTarget = uiswitch(app.UIFigure, 'slider');
            app.switchTarget.Items = {'0', '1'};
            app.switchTarget.ValueChangedFcn = createCallbackFcn(app, @switchTargetValueChanged, true);
            app.switchTarget.Value = '0';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Icon = 'xlsx_icon.svg';
            app.BrowseButton.Text = 'Browse';
            app.PauseButton = uibutton(app.UIFigure, 'state');
            app.PauseButton.ValueChangedFcn = createCallbackFcn(app, @PauseButtonValueChanged, true);
            app.PauseButton.Text = 'Pause';
            app.StopButton = uibutton(app.UIFigure, 'state');
            app.StopButton.Text = 'Stop execution';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NBCC_GroundMotionSelectionAPP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end