classdef NBCC_RecordFormattingAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        someNotes                 matlab.ui.control.TextArea
        SaT1forEqInfo             matlab.ui.control.NumericEditField
        StartifromLabel_3         matlab.ui.control.Label
        T1forEqInfoFile           matlab.ui.control.NumericEditField
        StartifromLabel_2         matlab.ui.control.Label
        eqIDinitial               matlab.ui.control.NumericEditField
        StartifromLabel           matlab.ui.control.Label
        eqInfoFilename            matlab.ui.control.EditField
        InputfileLabel_4          matlab.ui.control.Label
        ProcInfoOutput            matlab.ui.control.TextArea
        ProcessOutputLabel        matlab.ui.control.Label
        NoParallel                matlab.ui.control.CheckBox
        specDir                   matlab.ui.control.EditField
        ResultDirNameLabel_2      matlab.ui.control.Label
        secsAtEnd                 matlab.ui.control.NumericEditField
        sectoinsertattheendLabel  matlab.ui.control.Label
        fileNamingRule            matlab.ui.control.EditField
        InputfileLabel_2          matlab.ui.control.Label
        FileFormatType            matlab.ui.container.ButtonGroup
        FormatPSB                 matlab.ui.control.RadioButton
        FormatST                  matlab.ui.control.RadioButton
        rawRecDir                 matlab.ui.control.EditField
        InputfileLabel            matlab.ui.control.Label
        formRecDir                matlab.ui.control.EditField
        ResultDirNameLabel        matlab.ui.control.Label
        FormatRecsButton          matlab.ui.control.Button
        saveResSpec               matlab.ui.control.CheckBox
        fileNamingInstruction     matlab.ui.control.TextArea
    end

    
    properties (Access = public)
         % Description
    end
    
    methods (Access = private)
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,"center")
            app.UIFigure.Visible = 'on';  
            
        % This nice app needs other functions and hence matlab's working directory to be where they are saved, 
        % regardless of whether there is an existing instance of matlab (or what it's been doing)
            appPath = mfilename('fullpath'); 
            [filePath, ~, ~] = fileparts(appPath); cd(filePath); 
            
            if app.FormatST.Value == 1
                app.eqInfoFilename.Value = sprintf('DefineEarthquakeRecordInformation.tcl');
                app.eqIDinitial.Value = 1; 
                fileSuff = 'st';
                app.fileNamingRule.Editable = 'on';
                app.T1forEqInfoFile.Visible = 'on'; app.StartifromLabel_2.Visible = 'on';
                app.SaT1forEqInfo.Visible = 'on'; app.StartifromLabel_3.Visible = 'on';
                app.saveResSpec.Visible = 'off'; app.saveResSpec.Value = 0;
                app.fileNamingInstruction.Value = {'.'; ''; ''; ''; ''; ''; ''; ''; repmat('-', 1, 95);
                    '.                       Define the naming rule for formatted files.';  repmat('-', 1, 95);
                    '%dir is horizontal component indicator. It will be replaced by 1 or 2.';
                    '%i is EQ identifier. It is sequential. Set the first value in the box above.'; 
                    'Make sure to keep both strings, %dir and %i, in the rule.'; repmat('-', 1, 95)};
                app.someNotes.Value = {'Notes:'; '       1. KiK-net records on the surface are considered here.'; '       2. T1 and Sa(T1) entered above are required only for saving EQ-Info file (DefineEarthquakeRecordInformation.tcl).'};
                app.someNotes.Position = [24 276 378 60];
            elseif app.FormatPSB.Value == 1
                app.eqInfoFilename.Value = sprintf('defineEQInfoForMATLAB.m');
                app.eqIDinitial.Value = 7001;
                fileSuff = 'psb';
                app.fileNamingRule.Editable = 'off';
                app.T1forEqInfoFile.Visible = 'off'; app.StartifromLabel_2.Visible = 'off';
                app.SaT1forEqInfo.Visible = 'off'; app.StartifromLabel_3.Visible = 'off';
                app.saveResSpec.Visible = 'on'; app.saveResSpec.Value = 1;
                app.fileNamingInstruction.Value = {'.'; ''; ''; ''; ''; ''; ''; ''; repmat('-', 1, 95);
                    '.                       Define the naming rule for formatted files.';  repmat('-', 1, 95);
                    'Filenames are fixed as ''DtFile_(·)'' , ''NumPointsFile_(·)'', ''SortedEQFile_(·)''';
                    '%i is first event number. It is sequential. Set it above, e.g., 7001.';
                    'EqNumber then is set to 10 × %i + %dir (1 or 2), e.g., 70011, 70012, etc.'; repmat('-', 1, 95)};
                app.someNotes.Value = {'Notes:'; '       1. KiK-net records on the surface are considered here.';};
                app.someNotes.Position = [24 301 378 35];
            end

            app.formRecDir.Value = sprintf('%s_formatted_%s', app.rawRecDir.Value, fileSuff);

            if app.saveResSpec.Value == 1
                app.specDir.Visible = 'on'; app.ResultDirNameLabel_2.Visible = 'on';
                app.specDir.Value = sprintf('%s_EQ_Spectra_%s', app.rawRecDir.Value, fileSuff);
                app.NoParallel.Visible = 'off'; app.NoParallel.Value = false;
            else 
                app.specDir.Visible = 'off'; app.ResultDirNameLabel_2.Visible = 'off';
                app.NoParallel.Visible = 'off'; app.NoParallel.Value = false;
            end
        end

        % Button pushed function: FormatRecsButton
        function FormatRecsButtonPushed(app, event)
            app.ProcInfoOutput.Value = '';
        % assign all userInputs to a single variable
            userInputs.rawRecDir      = app.rawRecDir.Value;
            userInputs.FormatST       = app.FormatST.Value;
            userInputs.FormatPSB      = app.FormatPSB.Value;
            userInputs.fileNamingRule = app.fileNamingRule.Value;
            userInputs.eqIDinitial    = app.eqIDinitial.Value;
            userInputs.eqInfoFilename = app.eqInfoFilename.Value;
            userInputs.T1forEqInfoFile= app.T1forEqInfoFile.Value;
            userInputs.SaT1forEqInfo  = app.SaT1forEqInfo.Value;
            userInputs.dampRatioForEqInfoFile = 0.05; % this is used for EQ info file only

            userInputs.formRecDir     = app.formRecDir.Value;
            userInputs.secsAtEnd      = app.secsAtEnd.Value;
            userInputs.saveResSpec    = app.saveResSpec.Value;
            userInputs.NoParallel     = app.NoParallel.Value;
            userInputs.specDir        = app.specDir.Value;

        if isfile('masterFunctionFormattingApp.m')
            processInfo = masterFunctionFormattingApp(userInputs);
            % copy the proess information output to the window
            app.formRecDir.FontWeight = 'bold'; app.formRecDir.FontColor = [1 0 1]; 
            app.specDir.FontWeight = 'bold'; app.specDir.FontColor = [1 0 1]; 
        else % if the function was not found 
            processInfo = sprintf(['The master function %s is not found on the current directory. ' ...
                                   'Navigate MATLAB to the directory with the app and click on Format ' ...
                                   'Earthquake Records again.'], masterFunctionFormattingApp);
        end
            app.ProcInfoOutput.Value = processInfo;       
        end

        % Value changing function: rawRecDir
        function rawRecDirValueChanging(app, event)
            changingValue = event.Value;
            if app.FormatPSB.Value == 1; fileSuff = 'psb'; 
            elseif app.FormatST.Value == 1; fileSuff = 'st';
            end
            app.formRecDir.Value = sprintf('%s_formatted_%s', changingValue, fileSuff);
            app.specDir.Value = sprintf('%s_EQ_Spectra_%s', changingValue, fileSuff);
        end

        % Selection changed function: FileFormatType
        function FileFormatTypeSelectionChanged(app, event)
            if app.FormatST.Value == 1
                app.secsAtEnd.Value = 30;
                app.fileNamingRule.Value = 'H%dir_%i.dat';
                app.fileNamingRule.BackgroundColor = [1 1 1];
                app.fileNamingRule.FontColor = 'b';
                app.fileNamingRule.Editable = 'on';
                app.eqIDinitial.Value = 1;
                app.formRecDir.Value = sprintf('%s_formatted_%s', app.rawRecDir.Value, 'st');
                app.specDir.Value = sprintf('%s_EQ_Spectra_%s', app.rawRecDir.Value, 'st');
                app.eqInfoFilename.Value = sprintf('DefineEarthquakeRecordInformation.tcl');
                app.T1forEqInfoFile.Visible = 'on'; app.StartifromLabel_2.Visible = 'on';
                app.SaT1forEqInfo.Visible = 'on'; app.StartifromLabel_3.Visible = 'on';
                app.saveResSpec.Visible = 'off'; app.saveResSpec.Value = 0;
                app.specDir.Visible = 'off'; app.ResultDirNameLabel_2.Visible = 'off';
                app.fileNamingInstruction.Value = {'.'; ''; ''; ''; ''; ''; ''; ''; repmat('-', 1, 95);
                    '.                       Define the naming rule for formatted files.';  repmat('-', 1, 95);
                    '%dir is horizontal component indicator. It will be replaced by 1 or 2.';
                    '%i is EQ identifier. It is sequential. Set the first value in the box above.'; 
                    'Make sure to keep both strings, %dir and %i, in the rule.'; repmat('-', 1, 95)};
                app.someNotes.Value = {'Notes:'; '       1. KiK-net records on the surface are considered here.'; '       2. T1 and Sa(T1) entered above are required only for saving EQ-Info file (DefineEarthquakeRecordInformation.tcl).'};
                app.someNotes.Position = [24 276 378 60];
            elseif app.FormatPSB.Value == 1
                app.secsAtEnd.Value = 0;
                app.fileNamingRule.Value  = 'DtFile_(%i%dir).txt';
                app.fileNamingRule.BackgroundColor = [0.94, 0.94, 0.94];
                app.fileNamingRule.FontColor = 'k';
                app.fileNamingRule.Editable = 'off';
                app.eqIDinitial.Value = 7001;
                app.formRecDir.Value = sprintf('%s_formatted_%s', app.rawRecDir.Value, 'psb');
                app.specDir.Value = sprintf('%s_EQ_Spectra_%s', app.rawRecDir.Value, 'psb');
                app.eqInfoFilename.Value = sprintf('defineEQInfoForMATLAB.m');
                app.T1forEqInfoFile.Visible = 'off'; app.StartifromLabel_2.Visible = 'off';
                app.SaT1forEqInfo.Visible = 'off'; app.StartifromLabel_3.Visible = 'off';
                app.saveResSpec.Visible = 'on'; app.saveResSpec.Value = 1;
                app.specDir.Visible = 'on'; app.ResultDirNameLabel_2.Visible = 'on';
                app.fileNamingInstruction.Value = {'.'; ''; ''; ''; ''; ''; ''; ''; repmat('-', 1, 95);
                    '.                       Define the naming rule for formatted files.';  repmat('-', 1, 95);
                    'Filenames are fixed as ''DtFile_(·)'' , ''NumPointsFile_(·)'', ''SortedEQFile_(·)''';
                    '%i is first event number. It is sequential. Set it above, e.g., 7001.';
                    'EqNumber then is set to 10 × %i + %dir (1 or 2), e.g., 70011, 70012, etc.'; repmat('-', 1, 95)};
                app.someNotes.Value = {'Notes:'; '       1. KiK-net records on the surface are considered here.';};
                app.someNotes.Position = [24 301 378 35];
            end
        end

        % Value changed function: saveResSpec
        function saveResSpecValueChanged(app, event)
            value = app.saveResSpec.Value;
            if value == 1
                app.specDir.Visible = 'on'; app.ResultDirNameLabel_2.Visible = 'on';
                if app.FormatPSB.Value == 1; fileSuff = 'psb'; 
                elseif app.FormatST.Value == 1; fileSuff = 'st';
                end
                app.specDir.Value = sprintf('%s_EQ_Spectra_%s', app.rawRecDir.Value, fileSuff);
                app.NoParallel.Visible = 'off'; app.NoParallel.Value = false;
            else 
                app.specDir.Visible = 'off'; app.ResultDirNameLabel_2.Visible = 'off';
                app.NoParallel.Visible = 'off'; app.NoParallel.Value = false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.fileNamingInstruction = uitextarea(app.UIFigure);
            app.saveResSpec = uicheckbox(app.UIFigure);
            app.saveResSpec.ValueChangedFcn = createCallbackFcn(app, @saveResSpecValueChanged, true);
            app.saveResSpec.Text = 'Save Response Spectra';
            app.FormatRecsButton = uibutton(app.UIFigure, 'push');
            app.FormatRecsButton.ButtonPushedFcn = createCallbackFcn(app, @FormatRecsButtonPushed, true);
            app.FormatRecsButton.Text = 'Format Earthquake Records';
            app.ResultDirNameLabel = uilabel(app.UIFigure);
            app.ResultDirNameLabel.Text = 'Formatted records directory';
            app.formRecDir = uieditfield(app.UIFigure, 'text');
            app.formRecDir.Editable = 'off';
            app.InputfileLabel = uilabel(app.UIFigure);
            app.InputfileLabel.Text = 'Raw records directory';
            app.rawRecDir = uieditfield(app.UIFigure, 'text');
            app.rawRecDir.ValueChangingFcn = createCallbackFcn(app, @rawRecDirValueChanging, true);
            app.rawRecDir.Tooltip = {'Unzip all records (KiK-net, NGA) in this directory. Do not save within sub-folders.'};
            app.rawRecDir.Value = 'ScaledRaw';
            app.FileFormatType = uibuttongroup(app.UIFigure);
            app.FileFormatType.SelectionChangedFcn = createCallbackFcn(app, @FileFormatTypeSelectionChanged, true);
            app.FileFormatType.Title = 'Format for saving records';
            app.FormatST = uiradiobutton(app.FileFormatType);
            app.FormatST.Text = '1 file/record (.dat for ag) + EqInfo file (.tcl)';
            app.FormatST.Value = true;
            app.FormatPSB = uiradiobutton(app.FileFormatType);
            app.FormatPSB.Text = '3 files/record (.txt for dt, N, ag) + EqInfo file (.m)';
            app.InputfileLabel_2 = uilabel(app.UIFigure);
            app.InputfileLabel_2.Text = 'File naming rule';
            app.fileNamingRule = uieditfield(app.UIFigure, 'text');
            app.fileNamingRule.Value = 'H%dir_%i.dat';
            app.sectoinsertattheendLabel = uilabel(app.UIFigure);
            app.sectoinsertattheendLabel.Text = 'sec to insert at the end';
            app.secsAtEnd = uieditfield(app.UIFigure, 'numeric');
            app.secsAtEnd.Editable = 'off';
            app.secsAtEnd.Value = 30;
            app.ResultDirNameLabel_2 = uilabel(app.UIFigure);
            app.ResultDirNameLabel_2.Text = 'Spectra directory';
            app.specDir = uieditfield(app.UIFigure, 'text');
            app.specDir.Editable = 'off';
            app.NoParallel = uicheckbox(app.UIFigure);
            app.NoParallel.Text = 'Do NOT use parallelization';
            app.ProcessOutputLabel = uilabel(app.UIFigure);
            app.ProcessOutputLabel.Text = 'Process Output';
            app.ProcInfoOutput = uitextarea(app.UIFigure);
            app.ProcInfoOutput.Editable = 'off';
            app.ProcInfoOutput.Placeholder = 'Click on "Format Earthquake Records" to run the app.';
            app.InputfileLabel_4 = uilabel(app.UIFigure);
            app.InputfileLabel_4.Text = 'EqInfo filename';
            app.eqInfoFilename = uieditfield(app.UIFigure, 'text');
            app.eqInfoFilename.Editable = 'off';
            app.StartifromLabel = uilabel(app.UIFigure);
            app.StartifromLabel.Text = 'Start %i from';
            app.eqIDinitial = uieditfield(app.UIFigure, 'numeric');
            app.eqIDinitial.Value = 1;
            app.StartifromLabel_2 = uilabel(app.UIFigure);
            app.StartifromLabel_2.Text = 'T1 (s)';
            app.T1forEqInfoFile = uieditfield(app.UIFigure, 'numeric');
            app.T1forEqInfoFile.Tooltip = {'Required for scaing factor.'};
            app.StartifromLabel_3 = uilabel(app.UIFigure);
            app.StartifromLabel_3.Text = 'Sa(T1) (g)';
            app.SaT1forEqInfo = uieditfield(app.UIFigure, 'numeric');
            app.SaT1forEqInfo.Tooltip = {'Required for scaing factor.'};
            app.someNotes = uitextarea(app.UIFigure);
            app.someNotes.Value = {'Notes:'; '       1. KiK-net records on the surface are considered here.'; '       2. T1 and Sa(T1) entered above are required only for saving EQ-Info file (DefineEarthquakeRecordInformation.tcl).'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NBCC_RecordFormattingAPP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end